classdef RecoleccionDeDatosEMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        RecoleccindeDatosEMGUVGLabel    matlab.ui.control.Label
        CaracteristicasSeleccionadasPanel  matlab.ui.container.Panel
        ZeroCrossingCheckBox            matlab.ui.control.CheckBox
        MAVCheckBox                     matlab.ui.control.CheckBox
        KurtosisCheckBox                matlab.ui.control.CheckBox
        PromediodeEnergaCheckBox        matlab.ui.control.CheckBox
        VarianzaCheckBox                matlab.ui.control.CheckBox
        AmplituddeWilsonCheckBox        matlab.ui.control.CheckBox
        MediaCuadraticaCheckBox         matlab.ui.control.CheckBox
        CrearTablaButton                matlab.ui.control.Button
        RegresarButton                  matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        TomarMuestraButton              matlab.ui.control.Button
        GuardarArchivoButton            matlab.ui.control.Button
        TiempoEditFieldLabel            matlab.ui.control.Label
        TiempoEditField                 matlab.ui.control.EditField
        TiempodemuestraatomarEditFieldLabel  matlab.ui.control.Label
        TiempodemuestraatomarEditField  matlab.ui.control.NumericEditField
        NombreDelArchivoEditFieldLabel  matlab.ui.control.Label
        NombreDelArchivoEditField       matlab.ui.control.EditField
        figFpz                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CrearTablaButton
        function CrearTablaButtonPushed(app, event)
            
           global T
           global n 
           app.Lamp.Enable = 'off';
           n= 0; 
           F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda       
           F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda 
           
           T = table()
            if app.ZeroCrossingCheckBox.Value == 1
                T.ZC = zeros(0)
            end
            if app.MAVCheckBox.Value == 1
                T.MAV = zeros(0)
            end
            if app.KurtosisCheckBox.Value == 1
                T.Kurtosis = zeros(0)
            end
            if app.PromediodeEnergaCheckBox.Value == 1
                T.AverageE = zeros(0)
            end
          
            if app.VarianzaCheckBox.Value == 1
                T.Varianza = zeros(0)
            end
            if app.AmplituddeWilsonCheckBox.Value == 1
                T.AmplitudWilson = zeros(0)
            end
            if app.MediaCuadraticaCheckBox.Value == 1
                T.MediaCuadratica = zeros(0)
            end
        end

        % Button pushed function: TomarMuestraButton
        function TomarMuestraButtonPushed(app, event)
            global Tiempo_DMuestra
            global n 
            Tiempo_DMuestra = app.TiempodemuestraatomarEditField.Value;
            time =0;
                b = Bitalino;
                b.startBackground;
                % Pause to acquire data for 20 seconds
                %pause(10);
                % Read the data from the device
                
                
                app.Lamp.Enable = 'on';
            
                
                'HELLOO'
                %data = readCurrentValues(b, 5000)*5/255;
                while (time < Tiempo_DMuestra)
                    pause(1)
                    time = time + 1
                    app.TiempoEditField.Value = string(time)
                end

                %pause(Tiempo_DMuestra);
                % Read the data from the device
                data = b.read*5/1024;       
                %hola =          data(:,2)
                % Stop background acquisition of data
                b.stopBackground;
                % Clean up the bitalino object
                delete(b)
                
                app.TiempoEditField.Value = string(0)
                
                app.Lamp.Enable = 'off';
                
                F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda       
                F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda 
                data_f = filter(F_pb, data(:,6)');                          %Aplicar filtro pasa bandas
                data_n = filter(F_notch, data_f);                       %Aplicar filtro notch
                Epocas = length(data_n);

                
                Data.WaveFormC5EMG(1)= jWaveformLength(data_n);
                Data.MaximumFC5EMG(1) = jMaximumFractalLength(data_n);
                Data.SkewnessFC5EMG(1) = jSkewness(data_n);
       
                L = Epocas;
                t= linspace( 0, Epocas/1000, L);
                h1 = plot( app.figFpz,t, data_n)
                ylim(app.figFpz,[min(data_n) max(data_n)]);
                h1.YData = data_n
                n = n+1
                
            global T
            if app.ZeroCrossingCheckBox.Value == 1
                T.ZC(n) = jZeroCrossing(data_n,0.01);
             
            end
            if app.MAVCheckBox.Value == 1
                T.MAV(n) = jMeanAbsoluteDeviation(data_n);
             
            end
            if app.KurtosisCheckBox.Value == 1
                T.Kurtosis(n) = jKurtosis(data_n);
                
            end
            if app.PromediodeEnergaCheckBox.Value == 1
                T.AverageE(n) = jAverageEnergy(data_n);
                
            end
         
            if app.VarianzaCheckBox.Value == 1
                T.Varianza(n) = jVariance(data_n);
                
            end
            if app.AmplituddeWilsonCheckBox.Value == 1
                T.AmplitudWilson(n) = jWillisonAmplitude(data_n,0.01);
                
            end
            if app.MediaCuadraticaCheckBox.Value == 1
                T.MediaCuadratica(n) = jRootMeanSquare(data_n);
                
            end


        end

        % Button pushed function: GuardarArchivoButton
        function GuardarArchivoButtonPushed(app, event)
           global T
           global NombreA
            save('PruebaT.mat', 'T')
            NombreA = app.NombreDelArchivoEditField.Value
            writetable(T,string(NombreA)+"."+'xls')
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            Interfaz_Inicio
             delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RecoleccindeDatosEMGUVGLabel = uilabel(app.Panel);
            app.RecoleccindeDatosEMGUVGLabel.Text = 'Recolección de Datos  EMG - UVG';
            app.CaracteristicasSeleccionadasPanel = uipanel(app.UIFigure);
            app.CaracteristicasSeleccionadasPanel.Title = 'Caracteristicas Seleccionadas ';
            app.ZeroCrossingCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.ZeroCrossingCheckBox.Text = 'Zero Crossing';
            app.MAVCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MAVCheckBox.Text = 'MAV';
            app.KurtosisCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.KurtosisCheckBox.Text = 'Kurtosis';
            app.PromediodeEnergaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.PromediodeEnergaCheckBox.Text = 'Promedio de Energía';
            app.VarianzaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.VarianzaCheckBox.Text = 'Varianza';
            app.AmplituddeWilsonCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.AmplituddeWilsonCheckBox.Text = 'Amplitud de Wilson';
            app.MediaCuadraticaCheckBox = uicheckbox(app.CaracteristicasSeleccionadasPanel);
            app.MediaCuadraticaCheckBox.Text = 'Media Cuadratica ';
            app.CrearTablaButton = uibutton(app.CaracteristicasSeleccionadasPanel, 'push');
            app.CrearTablaButton.ButtonPushedFcn = createCallbackFcn(app, @CrearTablaButtonPushed, true);
            app.CrearTablaButton.Text = 'Crear Tabla';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.Lamp = uilamp(app.Panel_2);
            app.TomarMuestraButton = uibutton(app.Panel_2, 'push');
            app.TomarMuestraButton.ButtonPushedFcn = createCallbackFcn(app, @TomarMuestraButtonPushed, true);
            app.TomarMuestraButton.Text = 'Tomar Muestra';
            app.GuardarArchivoButton = uibutton(app.Panel_2, 'push');
            app.GuardarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarArchivoButtonPushed, true);
            app.GuardarArchivoButton.Text = 'Guardar Archivo';
            app.TiempoEditFieldLabel = uilabel(app.Panel_2);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.Panel_2, 'text');
            app.TiempodemuestraatomarEditFieldLabel = uilabel(app.Panel_2);
            app.TiempodemuestraatomarEditFieldLabel.Text = 'Tiempo de muestra a tomar';
            app.TiempodemuestraatomarEditField = uieditfield(app.Panel_2, 'numeric');
            app.TiempodemuestraatomarEditField.Limits = [0 25];
            app.TiempodemuestraatomarEditField.RoundFractionalValues = 'on';
            app.TiempodemuestraatomarEditField.Value = 1;
            app.NombreDelArchivoEditFieldLabel = uilabel(app.Panel_2);
            app.NombreDelArchivoEditFieldLabel.Text = 'Nombre Del Archivo';
            app.NombreDelArchivoEditField = uieditfield(app.Panel_2, 'text');
            app.NombreDelArchivoEditField.Value = 'NuevaTabla';
            app.figFpz = uiaxes(app.UIFigure);
            title(app.figFpz, 'Muestra Tomada')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecoleccionDeDatosEMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end